classdef Trajectory_Optimizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        OpenMenu                   matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        SpaceportsMenu             matlab.ui.container.Menu
        AsiaMenu                   matlab.ui.container.Menu
        JiuquanSLCCNMenu           matlab.ui.container.Menu
        TaiyuanSLCCNMenu           matlab.ui.container.Menu
        XichangSLCCNMenu           matlab.ui.container.Menu
        WenchangSLCCNMenu          matlab.ui.container.Menu
        SriharikotaINMenu          matlab.ui.container.Menu
        SemnanIRMenu               matlab.ui.container.Menu
        PalmachimISMenu            matlab.ui.container.Menu
        TanegashimaSCJPMenu        matlab.ui.container.Menu
        BaikonurCosmodromeKZMenu   matlab.ui.container.Menu
        SohaeNKMenu                matlab.ui.container.Menu
        NaroSCSKMenu               matlab.ui.container.Menu
        YasnyCosmodromeRUMenu      matlab.ui.container.Menu
        VostochnyCosmodromeRUMenu  matlab.ui.container.Menu
        EuropeMenu                 matlab.ui.container.Menu
        AndyaSCNOMenu              matlab.ui.container.Menu
        SvalRakNOMenu              matlab.ui.container.Menu
        AzoresPRMenu               matlab.ui.container.Menu
        KapustinYarRUMenu          matlab.ui.container.Menu
        PlesetskRUMenu             matlab.ui.container.Menu
        ElArenosilloSPMenu         matlab.ui.container.Menu
        SutherlandGBMenu           matlab.ui.container.Menu
        AmericasMenu               matlab.ui.container.Menu
        KourouFRGFMenu             matlab.ui.container.Menu
        PuertoBelgranoARMenu       matlab.ui.container.Menu
        AlcntaraSCBRMenu           matlab.ui.container.Menu
        WallopsUSMenu              matlab.ui.container.Menu
        WhiteSandsUSMenu           matlab.ui.container.Menu
        KennedySCUSMenu            matlab.ui.container.Menu
        VandenbergUSMenu           matlab.ui.container.Menu
        KodiakUSMenu               matlab.ui.container.Menu
        TexasLSUSMenu              matlab.ui.container.Menu
        OceaniaMenu                matlab.ui.container.Menu
        MahiaNZMenu                matlab.ui.container.Menu
        WhalersWayAUMenu           matlab.ui.container.Menu
        OthersMenu                 matlab.ui.container.Menu
        SeaLaunchMenu              matlab.ui.container.Menu
        MLPSputnikDKMenu           matlab.ui.container.Menu
        RocketsMenu                matlab.ui.container.Menu
        RussiaMenu                 matlab.ui.container.Menu
        StrelaMenu                 matlab.ui.container.Menu
        Kosmos3MMenu               matlab.ui.container.Menu
        USAMenu                    matlab.ui.container.Menu
        Falcon1Menu                matlab.ui.container.Menu
        Falcon9Menu                matlab.ui.container.Menu
        Falcon9v10Menu             matlab.ui.container.Menu
        Falcon9v11Menu             matlab.ui.container.Menu
        Falcon9FTMenu              matlab.ui.container.Menu
        Falcon9Block5Menu          matlab.ui.container.Menu
        ChinaMenu                  matlab.ui.container.Menu
        LongMarch2CMenu            matlab.ui.container.Menu
        LongMarch2DMenu            matlab.ui.container.Menu
        EuropeanUnionMenu          matlab.ui.container.Menu
        SouthKoreaMenu             matlab.ui.container.Menu
        Naro1Menu                  matlab.ui.container.Menu
        JapanMenu                  matlab.ui.container.Menu
        OthersMenu_2               matlab.ui.container.Menu
        Safir1BIRMenu              matlab.ui.container.Menu
        ElectronNZMenu             matlab.ui.container.Menu
        Cyclone4MUAMenu            matlab.ui.container.Menu
        AboutMenu                  matlab.ui.container.Menu
        UIAxes                     matlab.ui.control.UIAxes
        BeginSimulationButton      matlab.ui.control.Button
        GraphTypeButtonGroup       matlab.ui.container.ButtonGroup
        TrajectoryButton           matlab.ui.control.ToggleButton
        VelocityButton             matlab.ui.control.ToggleButton
        MassButton                 matlab.ui.control.ToggleButton
        DynamicPressureButton      matlab.ui.control.ToggleButton
        TemperatureButton          matlab.ui.control.ToggleButton
        AirDensityButton           matlab.ui.control.ToggleButton
        GroundTrackButton          matlab.ui.control.ToggleButton
        LiftoffSliderLabel         matlab.ui.control.Label
        LiftoffSlider              matlab.ui.control.Slider
        TabGroup                   matlab.ui.container.TabGroup
        TargetOrbitTab             matlab.ui.container.Tab
        PayloadMassEditFieldLabel  matlab.ui.control.Label
        PayloadMassEditField       matlab.ui.control.NumericEditField
        LatitudeLabel              matlab.ui.control.Label
        LatitudeEditField          matlab.ui.control.NumericEditField
        LongtitudeEditFieldLabel   matlab.ui.control.Label
        LongtitudeEditField        matlab.ui.control.NumericEditField
        OrbitinclinationLabel      matlab.ui.control.Label
        OrbitinclinationEditField  matlab.ui.control.NumericEditField
        TargetorbitEditFieldLabel  matlab.ui.control.Label
        TargetorbitEditField       matlab.ui.control.NumericEditField
        Switch                     matlab.ui.control.Switch
        TextArea                   matlab.ui.control.TextArea
        ApogeekmEditFieldLabel     matlab.ui.control.Label
        ApogeekmEditField          matlab.ui.control.NumericEditField
        PerigeekmEditFieldLabel    matlab.ui.control.Label
        PerigeekmEditField         matlab.ui.control.NumericEditField
        StagesTab                  matlab.ui.container.Tab
        PropellantEditFieldLabel   matlab.ui.control.Label
        PropellantEdit_1           matlab.ui.control.NumericEditField
        ThrustkNEditFieldLabel     matlab.ui.control.Label
        ThrustEdit_1               matlab.ui.control.NumericEditField
        IspEditFieldLabel          matlab.ui.control.Label
        IspEdit_1                  matlab.ui.control.NumericEditField
        BurnouttimeEditFieldLabel  matlab.ui.control.Label
        BurnoutEdit_1              matlab.ui.control.NumericEditField
        NozzleAreaLabel            matlab.ui.control.Label
        AreaEdit_1                 matlab.ui.control.NumericEditField
        StructureEditFieldLabel    matlab.ui.control.Label
        StructureEdit_1            matlab.ui.control.NumericEditField
        StructureEdit_2            matlab.ui.control.NumericEditField
        PropellantEdit_2           matlab.ui.control.NumericEditField
        ThrustEdit_2               matlab.ui.control.NumericEditField
        IspEdit_2                  matlab.ui.control.NumericEditField
        BurnoutEdit_2              matlab.ui.control.NumericEditField
        AreaEdit_2                 matlab.ui.control.NumericEditField
        StructureEdit_3            matlab.ui.control.NumericEditField
        PropellantEdit_3           matlab.ui.control.NumericEditField
        ThrustEdit_3               matlab.ui.control.NumericEditField
        IspEdit_3                  matlab.ui.control.NumericEditField
        BurnoutEdit_3              matlab.ui.control.NumericEditField
        AreaEdit_3                 matlab.ui.control.NumericEditField
        StructureEdit_4            matlab.ui.control.NumericEditField
        PropellantEdit_4           matlab.ui.control.NumericEditField
        ThrustEdit_4               matlab.ui.control.NumericEditField
        IspEdit_4                  matlab.ui.control.NumericEditField
        BurnoutEdit_4              matlab.ui.control.NumericEditField
        AreaEdit_4                 matlab.ui.control.NumericEditField
        StageAreaLabel             matlab.ui.control.Label
        DiameterEdit_1             matlab.ui.control.NumericEditField
        DiameterEdit_2             matlab.ui.control.NumericEditField
        DiameterEdit_3             matlab.ui.control.NumericEditField
        DiameterEdit_4             matlab.ui.control.NumericEditField
        BoosterNoEdit_1            matlab.ui.control.NumericEditField
        BoosterStructureEdit_1     matlab.ui.control.NumericEditField
        BoosterPropellantEdit_1    matlab.ui.control.NumericEditField
        BoosterThrustEdit_1        matlab.ui.control.NumericEditField
        BoosterIspEdit_1           matlab.ui.control.NumericEditField
        BoosterBurnoutEdit_1       matlab.ui.control.NumericEditField
        BoosterNozzleEdit_1        matlab.ui.control.NumericEditField
        BoostersCheckBox           matlab.ui.control.CheckBox
        BoosterDiameter_1          matlab.ui.control.NumericEditField
        ofStagesSpinnerLabel       matlab.ui.control.Label
        ofStagesSpinner            matlab.ui.control.Spinner
        PhasesTab                  matlab.ui.container.Tab
        TimesecEditFieldLabel      matlab.ui.control.Label
        TimesecEditField           matlab.ui.control.EditField
        PitchAngleEditFieldLabel   matlab.ui.control.Label
        PitchAngleEditField        matlab.ui.control.EditField
        PhasepointsTextAreaLabel   matlab.ui.control.Label
        PhasepointsTextArea        matlab.ui.control.TextArea
        TextArea_2                 matlab.ui.control.TextArea
        AdvancedTab                matlab.ui.container.Tab
        TickincrementSliderLabel   matlab.ui.control.Label
        TickincrementSlider        matlab.ui.control.Slider
        TimeSliderLabel_2          matlab.ui.control.Label
        TabGroup2                  matlab.ui.container.TabGroup
        TelemetryTab               matlab.ui.container.Tab
        TelemetryField_2           matlab.ui.control.TextArea
        Gauge                      matlab.ui.control.SemicircularGauge
        RocketTab                  matlab.ui.container.Tab
        Rocket_Parameters          matlab.ui.control.TextArea
        OrbitTab                   matlab.ui.container.Tab
        TelemetryField             matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        YY % Telemetry from graphs
        TT % Time data from graphs
        Ys % Telemetry from final orbit
        Earth % Planet Earth
        apseline % apseline coordinates: line apoapsis-periapsis
        T % period of orbit
        point % chosen point on graph
        point_2 % second point (in case we have two graphs at once
        text % telemetry information
        beta % Thrust direction angle
        launchparams % launch parameters for staging
        atmosphere % atmospheric values
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            view(app.UIAxes,[45 25]);  % Establish perspective view for graph
            app.UIAxes.ZLabel.String = 'Z, km';
            app.TargetorbitEditField.Enable = 'Off';
            %% Plot Earth and atmosphere (~86 km for now, TO DO - INCREASE BY CHEMICAL RESEARCH UP TO 1000KM WITH GRADIENT)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            [X,Y,Z] = sphere; % Generating atmosphere and Earth by NASA textures
            cdata1 = flip(imread('Earth_texture.jpg'), 1);
            cdata2 = flip(imread('Earth_clouds.jpg'), 1);
            hold(app.UIAxes,'on');
            app.Earth = surf(app.UIAxes, X*6378, Y*6378, Z*6378, 'EdgeColor', 'None', 'FaceColor', 'texturemap', 'CData', cdata1); 
            surf(app.UIAxes, X*6464, Y*6464, Z*6464, 'EdgeColor', 'None','FaceAlpha',  0.25, 'FaceColor', 'texturemap', 'CData', cdata2);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% Atmospheric values from XLS spreadseet
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.atmosphere = xlsread("Constants.xlsx");
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: BeginSimulationButton
        function StartButtonPushed(app, event)
            %app.UIAxes.cla; % Clear plot if it was second time
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% Assigning initial rocket values  
            %% launchparams [m_struct_1,m_prop_1,m_struct_2,m_prop_2,Isp_1,Isp_2,T_1,T_2,t_burn_1,t_burn_2, A_e_1, A_e_2, S_1, S_2]
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.launchparams(1) = app.StructureEdit_1.Value; app.launchparams(2) = app.PropellantEdit_1.Value; 
            app.launchparams(3) = app.StructureEdit_2.Value; app.launchparams(4) = app.PropellantEdit_2.Value; 
            app.launchparams(5) = app.IspEdit_1.Value; app.launchparams(6) = app.IspEdit_2.Value; 
            app.launchparams(7) = app.ThrustEdit_1.Value; app.launchparams(8) = app.ThrustEdit_2.Value; 
            app.launchparams(9) = app.BurnoutEdit_1.Value; app.launchparams(10) = app.BurnoutEdit_2.Value; 
            app.launchparams(11) = app.AreaEdit_1.Value; app.launchparams(12) = app.AreaEdit_2.Value;
            app.launchparams(13) = pi*app.DiameterEdit_1.Value*app.DiameterEdit_1.Value/4; app.launchparams(14) = pi*app.DiameterEdit_2.Value*app.DiameterEdit_2.Value/4;
            app.Rocket_Parameters.Value = sprintf('Stage 1 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m \n Stage 2 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m', app.launchparams(1), app.launchparams(3), app.launchparams(5), app.launchparams(7), app.launchparams(9), app.launchparams(13), app.launchparams(2), app.launchparams(4), app.launchparams(6), app.launchparams(8), app.launchparams(10), app.launchparams(14));
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% Set Time increment of slider
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.LiftoffSlider.Limits = [0, app.launchparams(9)+2+app.launchparams(10)];
            app.LiftoffSlider.MajorTicks = [0, app.launchparams(9), 0.25*0.5*app.launchparams(9), 0.5*app.launchparams(9), app.launchparams(9), app.launchparams(9)+2+0.5*app.launchparams(10), app.launchparams(9)+2+app.launchparams(10)];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.LiftoffSlider.Enable = 'On';
            app.SaveMenu.Enable = 'On';
            d = uiprogressdlg(app.UIFigure,'Title','Trajectory Simulation','Message','Processing data','Cancelable','on','Indeterminate','on'); 
            switch app.Switch.Value
                case 'Simulation'
                    [app.TT, app.YY, app.beta] = main_calculation(app.PayloadMassEditField.Value,app.TickincrementSlider.Value,app.LatitudeEditField.Value,app.OrbitinclinationEditField.Value, 1, app.launchparams);
                case 'Optimization'
                    [app.TT, app.YY, app.beta] = main_calculation_optimization(app.TargetorbitEditField.Value, app.TickincrementSlider.Value,app.LatitudeEditField.Value,app.OrbitinclinationEditField.Value, 2, app.launchparams);
            end
            plot3(app.UIAxes,app.YY(1,:),app.YY(2,:),app.YY(3,:),'-k');
            app.point = plot3(app.UIAxes, app.YY(1, 1), app.YY(2, 1), app.YY(3, 1), 'og', 'MarkerFaceColor','g', 'MarkerSize',3);
            hold(app.UIAxes,'on');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% Plot Orbit based on final coordinate of ascend
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            initial_data = app.YY(:,end);
            [app.Ys, app.apseline, app.T] = final_orbit(initial_data(1),initial_data(2),initial_data(3),initial_data(4),initial_data(5),initial_data(6));
            plot3(app.UIAxes, app.Ys(1,:), app.Ys(2,:), app.Ys(3,:),'-k');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            plot3(app.UIAxes, app.apseline(1,:), app.apseline(2,:), app.apseline(3,:),'-r');
            h_terminal = sqrt(app.YY(1,end)^2+app.YY(2,end)^2+app.YY(3,end)^2)-6378;
            v_terminal = sqrt(app.YY(4,end)^2+app.YY(5,end)^2+app.YY(6,end)^2);
            periapsis = sqrt(app.apseline(1,1)^2+app.apseline(2,1)^2+app.apseline(3,1)^2)-6378;
            apoapsis = sqrt(app.apseline(1,2)^2+app.apseline(2,2)^2+app.apseline(3,2)^2)-6378;
            period = 2*pi*sqrt((periapsis/2+apoapsis/2+6378)^3/8/398600);
            dot_product = dot([app.YY(1,end),app.YY(2,end),app.YY(3,end)],[app.YY(4,end),app.YY(5,end),app.YY(6,end)]);
            app.text = sprintf('Terminal altitude: %.3f km \n Terminal velocity: %.3f km/s \n Orbit periapsis: %.3f km \n Orbit apoapsis: %.3f km \n Orbit period: %.3f sec \n Dot product: %.3f \n Payload mass: %.3f', [h_terminal,v_terminal,periapsis,apoapsis, period, dot_product, app.YY(7,end)-544]);
            app.TelemetryField.Value = app.text;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            hold(app.UIAxes,'off');
            axis(app.UIAxes, 'equal');
            app.TrajectoryButton.Enable = 'on'; app.VelocityButton.Enable = 'on'; app.MassButton.Enable = 'on';
            app.DynamicPressureButton.Enable = 'on'; app.TemperatureButton.Enable = 'on'; app.AirDensityButton.Enable = 'on';
%            app.GroundTrackButton.Enable = 'on';
            close(d);
            app.LiftoffSlider.Visible = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = questdlg('Are you sure you want to finish?','Confirmation','Yes','No','Yes');
                switch selection
                    case 'Yes'
                        delete(app)
                    case 'No'
                        return
                end
        end

        % Selection changed function: GraphTypeButtonGroup
        function GraphTypeButtonGroupSelectionChanged(app, event)
            app.UIAxes.cla;
            selectedButton = app.GraphTypeButtonGroup.SelectedObject;
            v = sqrt(app.YY(4,:).^2+(app.YY(5,:)-0.465101*cosd(app.LatitudeEditField.Value)).^2+app.YY(6,:).^2); % Relative velocity computation from vx, vy, vz
            h = sqrt(app.YY(1,:).^2+(app.YY(2,:)).^2+app.YY(3,:).^2)-6378;
            rho = interp1(app.atmosphere(:,1), app.atmosphere(:,4), h);
            if selectedButton == app.TrajectoryButton % Trajectory Graph
                app.LiftoffSlider.Visible = 'on';
                app.UIAxes.Title.String = 'Launch vehicle trajectory';
                app.UIAxes.XLabel.String = 'X, km';
                app.UIAxes.YLabel.String = 'Y, km';
                app.UIAxes.ZLabel.String = 'Z, km';
                view(app.UIAxes,[45 25]);
                app.UIAxes.XLim = [-8000 8000];
                app.UIAxes.YLim = [-8000 8000];
                app.UIAxes.ZLim = [-8000 8000];
                [X,Y,Z] = sphere; % Generating atmosphere and Earth by NASA textures
                cdata1 = flip( imread('Earth_texture.jpg'), 1 );
                cdata2 = flip( imread('Earth_clouds.jpg'), 1 );
                app.Earth = surf(app.UIAxes, X*6378, Y*6378, Z*6378, 'EdgeColor', 'None', 'FaceColor', 'texturemap', 'CData', cdata1); hold(app.UIAxes,'on');
                rotate(app.Earth, [0 0 1], -app.LongtitudeEditField.Value);
                surf(app.UIAxes, X*6464, Y*6464, Z*6464, 'EdgeColor', 'None','FaceAlpha',  0.25, 'FaceColor', 'texturemap', 'CData', cdata2);
                plot3(app.UIAxes, app.YY(1,:), app.YY(2,:), app.YY(3,:), '-k'); % Launch Trajectory
                plot3(app.UIAxes, app.Ys(1,:), app.Ys(2,:), app.Ys(3,:), '-k'); % Orbit Trajectory
                plot3(app.UIAxes, app.apseline(1,:), app.apseline(2,:), app.apseline(3,:),'-r');
                hold(app.UIAxes,'off');
                axis(app.UIAxes, 'equal');
            elseif selectedButton == app.VelocityButton % Velocity Graph
                app.UIAxes.Title.String = 'Rocket velocity';
                app.UIAxes.XLabel.String = 'Time, s';
                app.UIAxes.YLabel.String = 'Velocity, km/s';
                app.UIAxes.ZLabel.String = '';
                view(app.UIAxes,2); % 2-D view
                plot(app.UIAxes,app.TT,v,'-k');
                axis(app.UIAxes, 'square');
            elseif selectedButton == app.MassButton % Mass Graph
                app.UIAxes.Title.String = 'Rocket mass';
                app.UIAxes.XLabel.String = 'Time, s';
                app.UIAxes.YLabel.String = 'Mass, kg';
                app.UIAxes.ZLabel.String = '';
                view(app.UIAxes,2); % 2-D View
                plot(app.UIAxes,app.TT,app.YY(7,:),'-k');
                axis(app.UIAxes, 'square');
            elseif selectedButton == app.DynamicPressureButton % Dynamic Pressure
                app.UIAxes.Title.String = 'Dynamic Pressure';
                app.UIAxes.XLabel.String = 'Time, s';
                app.UIAxes.YLabel.String = 'Dynamic Pressure, atm';
                app.UIAxes.ZLabel.String = '';
                view(app.UIAxes,2); % 2-D View
                axis(app.UIAxes, 'square');
                app.UIAxes.YLim = [0 1];
                p_static = interp1(app.atmosphere(:,1), app.atmosphere(:,3), h)./101325; % atmospheric pressure
                p_dynamic = (rho.*v.^2)/2*1000000/101325; % dynamic pressure in atmospheres
                plot(app.UIAxes,app.TT,p_dynamic,'-k'); hold(app.UIAxes,'on'); plot(app.UIAxes,app.TT,p_static,'-b');
            elseif selectedButton == app.TemperatureButton % Air Density and Temperature
                app.UIAxes.Title.String = 'Atmospheric Temperature';
                app.UIAxes.YLabel.String = 'Temperature, K';
                app.UIAxes.XLabel.String = 'Time, s';
                app.UIAxes.ZLabel.String = '';
                view(app.UIAxes,2); % 2-D View
                app.UIAxes.YLim = [100 1000];
                Temp = interp1(app.atmosphere(:,1), app.atmosphere(:,2), h);
                plot(app.UIAxes,app.TT,Temp, '-k'); % Temperature
            elseif selectedButton == app.AirDensityButton 
                app.UIAxes.Title.String = 'Air Density';
                app.UIAxes.XLabel.String = 'Time, s';
                app.UIAxes.YLabel.String = 'Air Density, kg/m3';
                app.UIAxes.ZLabel.String = '';
                view(app.UIAxes,2); % 2-D View
                app.UIAxes.YLim = [0 1.225];
                plot(app.UIAxes,app.TT,rho, '-k'); % Air Density
                axis(app.UIAxes, 'square');
%             elseif selectedButton == app.GroundTrackButton
%                 app.UIAxes.Title.String = 'Trajectory Ground Track';
%                 app.UIAxes.XLabel.String = 'Longitude';
%                 app.UIAxes.YLabel.String = 'Latitude';
%                 app.UIAxes.ZLabel.String = '';
%                 view(app.UIAxes,2); % 2-D View
%                 imshow('Earth_texture.jpg','Parent',app.UIAxes); % Earth Map
%                 hold(app.UIAxes, 'on');
%                 initial_data = app.YY(:,end);
%                 [app.Ys, app.apseline, app.T] = final_orbit(initial_data(1),initial_data(2),initial_data(3),initial_data(4),initial_data(5),initial_data(6));
%                 YY_lla_launch = ecef2lla([1000*app.YY(1,:) 1000*app.YY(2,:) 1000*app.YY(3,:)]);
%                 YY_lla_orbit = ecef2lla([1000*app.Ys(1,:) 1000*app.Ys(2,:) 1000*app.Ys(3,:)]);
%                 plot3(app.UIAxes, YY_lla_launch(1,:), YY_lla_launch(2,:),'-r');
%                 plot3(app.UIAxes, YY_lla_orbit(1,:), YY_lla_orbit(2,:),'-b');
            end
        end

        % Menu selected function: SeaLaunchMenu
        function SeaLaunchMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('0');
            app.LongtitudeEditField.Value = str2double('0');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: JiuquanSLCCNMenu
        function JiuquanSLCCNMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('40.96056');
            app.LongtitudeEditField.Value = str2double('100.29833');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: TaiyuanSLCCNMenu
        function TaiyuanSLCCNMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('39.14321');
            app.LongtitudeEditField.Value = str2double('111.96741');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: XichangSLCCNMenu
        function XichangSLCCNMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('28.24646');
            app.LongtitudeEditField.Value = str2double('102.02814');  
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: WenchangSLCCNMenu
        function WenchangSLCCNMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('19.61449');
            app.LongtitudeEditField.Value = str2double('110.9511');   
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: SriharikotaINMenu
        function SriharikotaINMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('13.73740');
            app.LongtitudeEditField.Value = str2double('80.23510');  
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: SemnanIRMenu
        function SemnanIRMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('35.234631');
            app.LongtitudeEditField.Value = str2double('53.920941');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: PalmachimISMenu
        function PalmachimISMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('31.884444');
            app.LongtitudeEditField.Value = str2double('34.680278');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: TanegashimaSCJPMenu
        function TanegashimaSCJPMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('30.39096');
            app.LongtitudeEditField.Value = str2double('130.96813');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: BaikonurCosmodromeKZMenu
        function BaikonurCosmodromeKZMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('45.95515');
            app.LongtitudeEditField.Value = str2double('63.35028');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: SohaeNKMenu
        function SohaeNKMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('39.660');
            app.LongtitudeEditField.Value = str2double('124.705');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: NaroSCSKMenu
        function NaroSCSKMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('34.42585');
            app.LongtitudeEditField.Value = str2double('127.52793');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: YasnyCosmodromeRUMenu
        function YasnyCosmodromeRUMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('51.20706');
            app.LongtitudeEditField.Value = str2double('59.85003');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: VostochnyCosmodromeRUMenu
        function VostochnyCosmodromeRUMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('51.883');
            app.LongtitudeEditField.Value = str2double('128.333');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: AndyaSCNOMenu
        function AndyaSCNOMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('69.29430');
            app.LongtitudeEditField.Value = str2double('16.02070');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: SvalRakNOMenu
        function SvalRakNOMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('78.2234');
            app.LongtitudeEditField.Value = str2double('15.6470');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: KapustinYarRUMenu
        function KapustinYarRUMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('48.57807');
            app.LongtitudeEditField.Value = str2double('46.25420');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: PlesetskRUMenu
        function PlesetskRUMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('62.92556');
            app.LongtitudeEditField.Value = str2double('40.57778'); 
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: ElArenosilloSPMenu
        function ElArenosilloSPMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('37.09687');
            app.LongtitudeEditField.Value = str2double('-6.73863'); % minus for western hemisphere
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: SutherlandGBMenu
        function SutherlandGBMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('58.5107');
            app.LongtitudeEditField.Value = str2double('-4.5121'); 
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: AzoresPRMenu
        function AzoresPRMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('36.941627');
            app.LongtitudeEditField.Value = str2double('-25.071900'); 
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: KourouFRGFMenu
        function KourouFRGFMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('5.23739');
            app.LongtitudeEditField.Value = str2double('-52.76950');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: PuertoBelgranoARMenu
        function PuertoBelgranoARMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('-38.9628'); % minus for southern hemisphere
            app.LongtitudeEditField.Value = str2double('-61.715');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: AlcntaraSCBRMenu
        function AlcntaraSCBRMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('-2.3160');
            app.LongtitudeEditField.Value = str2double('-44.3676');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: WallopsUSMenu
        function WallopsUSMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('37.84621');
            app.LongtitudeEditField.Value = str2double('-75.47938');  
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: WhiteSandsUSMenu
        function WhiteSandsUSMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('32.56460');
            app.LongtitudeEditField.Value = str2double('-106.35908');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: KennedySCUSMenu
        function KennedySCUSMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('28.6082');
            app.LongtitudeEditField.Value = str2double('-80.6040');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: VandenbergUSMenu
        function VandenbergUSMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('34.77204');
            app.LongtitudeEditField.Value = str2double('-120.60124');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: KodiakUSMenu
        function KodiakUSMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('57.43533');
            app.LongtitudeEditField.Value = str2double('-152.33931');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: TexasLSUSMenu
        function TexasLSUSMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('25.996');
            app.LongtitudeEditField.Value = str2double('-97.1541');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: MahiaNZMenu
        function MahiaNZMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('-39.26044');
            app.LongtitudeEditField.Value = str2double('177.86431');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: WhalersWayAUMenu
        function WhalersWayAUMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('-34.934');
            app.LongtitudeEditField.Value = str2double('135.6523');
            app.LatitudeEditFieldValueChanged;
        end

        % Menu selected function: MLPSputnikDKMenu
        function MLPSputnikDKMenuSelected(app, event)
            app.LatitudeEditField.Value = str2double('55.04917');
            app.LongtitudeEditField.Value = str2double('15.60306');
            app.LatitudeEditFieldValueChanged;
        end

        % Value changed function: LongtitudeEditField
        function LongtitudeEditFieldValueChanged(app, event)
            app.UIAxes.cla;
            [X,Y,Z] = sphere; % Generating atmosphere and Earth by NASA textures
            cdata1 = flip(imread('Earth_texture.jpg'), 1);
            cdata2 = flip(imread('Earth_clouds.jpg'), 1);
            hold(app.UIAxes,'on');
            app.Earth = surf(app.UIAxes, X*6378, Y*6378, Z*6378, 'EdgeColor', 'None', 'FaceColor', 'texturemap', 'CData', cdata1); 
            surf(app.UIAxes, X*6464, Y*6464, Z*6464, 'EdgeColor', 'None','FaceAlpha',  0.25, 'FaceColor', 'texturemap', 'CData', cdata2);
            rotate(app.Earth, [0 0 1], -app.LongtitudeEditField.Value); % by default rotates CW, '-' for CCW, [0 0 1] for Z-axis
            x0 = [6378*cosd(app.LatitudeEditField.Value), 0, 6378*sind(app.LatitudeEditField.Value)]; 
            plot3(app.UIAxes, x0(1),x0(2),x0(3), '-ro','MarkerFaceColor','r', 'MarkerSize',3);
            hold(app.UIAxes,'off');
        end

        % Value changed function: LatitudeEditField
        function LatitudeEditFieldValueChanged(app, event)
            app.UIAxes.cla;
            [X,Y,Z] = sphere; % Generating atmosphere and Earth by NASA textures
            cdata1 = flip(imread('Earth_texture.jpg'), 1);
            cdata2 = flip(imread('Earth_clouds.jpg'), 1);
            hold(app.UIAxes,'on');
            app.Earth = surf(app.UIAxes, X*6378, Y*6378, Z*6378, 'EdgeColor', 'None', 'FaceColor', 'texturemap', 'CData', cdata1); 
            surf(app.UIAxes, X*6464, Y*6464, Z*6464, 'EdgeColor', 'None','FaceAlpha',  0.25, 'FaceColor', 'texturemap', 'CData', cdata2);
            rotate(app.Earth, [0 0 1], -app.LongtitudeEditField.Value); % by default rotates CW, '-' for CCW, [0 0 1] for Z-axis
            x0 = [6378*cosd(app.LatitudeEditField.Value), 0, 6378*sind(app.LatitudeEditField.Value)]; 
            plot3(app.UIAxes, x0(1),x0(2),x0(3), '-ro','MarkerFaceColor','r', 'MarkerSize',3);
            hold(app.UIAxes,'off');
            %%%% Edit Inclination Limits %%%
            limits = [abs(app.LatitudeEditField.Value), 180-abs(app.LatitudeEditField.Value)];
            app.OrbitinclinationEditField.Limits = limits;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            app.UIFigureCloseRequest();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key; % keyboard shortcuts, easy to implement new through case
            switch key
                case 'r'
                    rotate3d(app.UIAxes,'on'); % toggle rotation of graph
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            switch app.Switch.Value
                case 'Simulation'
                    app.PayloadMassEditField.Editable = 'On';
                    app.PayloadMassEditField.Enable = 'On';
                    app.TargetorbitEditField.Enable = 'Off';
                    app.ApogeekmEditField.Enable = 'off';
                    app.PerigeekmEditField.Enable = 'off';
                case 'Optimization'
                    app.PayloadMassEditField.Editable = 'Off';
                    app.PayloadMassEditField.Enable = 'Off';
                    app.TargetorbitEditField.Enable = 'On';
                    app.ApogeekmEditField.Enable = 'on';
                    app.PerigeekmEditField.Enable = 'on';
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            message = sprintf('Launch Trajectory Opimization Tool v1.0 \n Author: Denis Groshev, June 2020 \n Made as part of term project for AE280 course. \n Future plans: \n1) Optimize code and remove unnecessary parts \n2) Add detailed rocket design \n3) Add multiple maneuver and advanced coasting handler. \n4) Improve performance and lower computational time');
            uialert(app.UIFigure,message,'About','Icon','info');
        end

        % Value changed function: LiftoffSlider
        function LiftoffSliderValueChanged(app, event)
            time_interval = [0,0.05226*app.launchparams(9),0.10452*app.launchparams(9),0.15679*app.launchparams(9),0.26131*app.launchparams(9),0.39197*app.launchparams(9),0.52263*app.launchparams(9),0.73168*app.launchparams(9),0.91460*app.launchparams(9), app.launchparams(9)-2,app.launchparams(9),app.launchparams(9)+2,app.launchparams(9)+2+0.06097*app.launchparams(10),app.launchparams(9)+2+0.15854*app.launchparams(10),app.launchparams(9)+2+0.28049*app.launchparams(10),app.launchparams(9)+2+0.37805*app.launchparams(10),app.launchparams(9)+2+0.47561*app.launchparams(10),app.launchparams(9)+2+0.54878*app.launchparams(10),app.launchparams(9)+2+0.71951*app.launchparams(10),app.launchparams(9)+2+0.89024*app.launchparams(10),app.launchparams(9)+2+app.launchparams(10)];
            value = round(app.LiftoffSlider.Value/app.TickincrementSlider.Value); 
            if value == 0
                value = 1;
            end
            app.Gauge.Value = interp1(time_interval,app.beta,value*app.TickincrementSlider.Value);
            hold(app.UIAxes,'on');
            h=sqrt(app.YY(1,:).^2+app.YY(2,:).^2+app.YY(3,:).^2)-6378; v = sqrt(app.YY(4,:).^2+(app.YY(5,:)-0.465101*cosd(app.LatitudeEditField.Value)).^2+app.YY(6,:).^2);
            altitudes = round(h, 2);
            velocities = round(v, 2);
            %%% Atmospheric Values %%%
            rho = interp1(app.atmosphere(:,1), app.atmosphere(:,4), h);
            Temp = interp1(app.atmosphere(:,1), app.atmosphere(:,2), h);
            p_static = interp1(app.atmosphere(:,1), app.atmosphere(:,3), h)./101325; % atmospheric pressure
            p_dynamic = (rho.*v.^2)/2*1000000/101325; % dynamic pressure in atmospheres
            %%%
            app.TelemetryField_2.Value = sprintf('Current time: T+%.3f s \n X: %.3f km \n Y: %.3f km \n Z: %.3f km \n m: %.3f kg \n Vx: %.3f km/s \n Vy: %.3f km/s \n Vz: %.3f km/s \n Mass: %.3f kg \n Pitch angle: %.3f ° \n Altitude: %.3f km \n Velocity: %.3f km/s \n Static Pressure: %.3f atm \n Dynamic Pressure: %.3f atm \n Air Density: %3.f kg/m3 \n Temperature: %3.f K', value*app.TickincrementSlider.Value, app.YY(1, value), app.YY(2, value), app.YY(3, value), app.YY(7, value), app.YY(4, value), app.YY(5, value), app.YY(6, value), app.YY(7, value), app.Gauge.Value, altitudes(value), velocities(value), p_static(value), p_dynamic(value), rho(value), Temp(value));
            if ishandle(app.point)
                delete(app.point)
            end
            if ishandle(app.point_2)
                delete(app.point_2)
            end
            switch app.GraphTypeButtonGroup.SelectedObject.Text
                case 'Trajectory'
                    app.point = plot3(app.UIAxes, app.YY(1, value), app.YY(2, value), app.YY(3, value), 'og', 'MarkerFaceColor','g', 'MarkerSize',3);
                case 'Velocity'
                    app.point = plot(app.UIAxes, value*app.TickincrementSlider.Value, velocities(value), 'og', 'MarkerFaceColor','g', 'MarkerSize',3);
                case 'Mass'
                    app.point = plot(app.UIAxes, value*app.TickincrementSlider.Value, app.YY(7, value), 'og', 'MarkerFaceColor','g', 'MarkerSize',3);
                case 'Dynamic Pressure'
                    app.point = plot(app.UIAxes, value*app.TickincrementSlider.Value, p_static(value), 'og', 'MarkerFaceColor','g', 'MarkerSize',3);
                    app.point_2 = plot(app.UIAxes, value*app.TickincrementSlider.Value, p_dynamic(value), 'og', 'MarkerFaceColor','g', 'MarkerSize',3);
                case 'Air Density'
                    app.point = plot(app.UIAxes, value*app.TickincrementSlider.Value, rho(value), 'og', 'MarkerFaceColor','g', 'MarkerSize',3);
                case 'Temperature'
                    app.point = plot(app.UIAxes, value*app.TickincrementSlider.Value, Temp(value), 'og', 'MarkerFaceColor','g', 'MarkerSize',3);
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            altitudes = sqrt(app.YY(1,:).^2+app.YY(2,:).^2+app.YY(3,:).^2)-6378;
            velocities = sqrt(app.YY(4,:).^2+app.YY(5,:).^2+app.YY(6,:).^2); % inertial velocity
            v = sqrt(app.YY(4,:).^2+(app.YY(5,:)-0.465101*cosd(app.LatitudeEditField.Value)).^2+app.YY(6,:).^2); % relative velocity
            h = sqrt(app.YY(1,:).^2+(app.YY(2,:)).^2+app.YY(3,:).^2)-6378;
            rho = interp1(app.atmosphere(:,1), app.atmosphere(:,4), h);
            p_dynamic = (rho.*v.^2)/2*1000000/101325; %pressure in atmospheres
            telemetry = [app.TT; app.YY; altitudes; velocities; rho; p_dynamic]';
            [file,path] = uiputfile('launch_telemetry.xlsx');
            writematrix(telemetry, strcat(path,file));
        end

        % Menu selected function: Naro1Menu
        function Naro1MenuSelected(app, event)
            % launchparams [m_struct_1,m_prop_1,m_struct_2,m_prop_2,Isp_1,Isp_2,T_1,T_2,t_burn_1,t_burn_2, A_e_1, A_e_2, S_1, S_2]
            app.launchparams = [10500, 127500, 200, 1300, 338, 250, 1670, 86.2, 229, 58, 0.735559, 0.2, 6.60519855417, 6.60519855417];
            app.StructureEdit_1.Value = app.launchparams(1); app.PropellantEdit_1.Value = app.launchparams(2); 
            app.StructureEdit_2.Value = app.launchparams(3); app.PropellantEdit_2.Value = app.launchparams(4); 
            app.IspEdit_1.Value = app.launchparams(5); app.IspEdit_2.Value = app.launchparams(6); 
            app.ThrustEdit_1.Value = app.launchparams(7); app.ThrustEdit_2.Value = app.launchparams(8); 
            app.BurnoutEdit_1.Value = app.launchparams(9); app.BurnoutEdit_2.Value = app.launchparams(10); 
            app.AreaEdit_1.Value = app.launchparams(11); app.AreaEdit_2.Value = app.launchparams(12);
            app.DiameterEdit_1.Value = app.launchparams(13); app.DiameterEdit_2.Value = app.launchparams(14);
            app.Rocket_Parameters.Value = sprintf('Stage 1 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m \n Stage 2 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m', app.launchparams(1), app.launchparams(3), app.launchparams(5), app.launchparams(7), app.launchparams(9), app.launchparams(13), app.launchparams(2), app.launchparams(4), app.launchparams(6), app.launchparams(8), app.launchparams(10), app.launchparams(14));
        end

        % Value changed function: BoostersCheckBox
        function BoostersCheckBoxValueChanged(app, event)
            value = app.BoostersCheckBox.Value;
            if value == 1 && app.ofStagesSpinner.Value > 1
                app.BoosterNoEdit_1.Enable = 'on';
                app.BoosterStructureEdit_1.Enable = 'on';
                app.BoosterPropellantEdit_1.Enable = 'on';
                app.BoosterThrustEdit_1.Enable = 'on';
                app.BoosterIspEdit_1.Enable = 'on';
                app.BoosterBurnoutEdit_1.Enable = 'on';
                app.BoosterNozzleEdit_1.Enable = 'on';
                app.BoosterDiameter_1.Enable = 'on';
            else
                app.BoosterNoEdit_1.Enable = 'off';
                app.BoosterStructureEdit_1.Enable = 'off';
                app.BoosterPropellantEdit_1.Enable = 'off';
                app.BoosterThrustEdit_1.Enable = 'off';
                app.BoosterIspEdit_1.Enable = 'off';
                app.BoosterBurnoutEdit_1.Enable = 'off';
                app.BoosterNozzleEdit_1.Enable = 'off';
                app.BoosterDiameter_1.Enable = 'off';
            end
        end

        % Value changed function: ofStagesSpinner
        function ofStagesSpinnerValueChanged(app, event)
            switch app.ofStagesSpinner.Value
                case 1
                app.StructureEdit_2.Enable = 'off';
                app.PropellantEdit_2.Enable = 'off';
                app.ThrustEdit_2.Enable = 'off';
                app.IspEdit_2.Enable = 'off';
                app.BurnoutEdit_2.Enable = 'off';
                app.AreaEdit_2.Enable = 'off';
                app.DiameterEdit_2.Enable = 'off';
                
                app.StructureEdit_3.Enable = 'off';
                app.PropellantEdit_3.Enable = 'off';
                app.ThrustEdit_3.Enable = 'off';
                app.IspEdit_3.Enable = 'off';
                app.BurnoutEdit_3.Enable = 'off';
                app.AreaEdit_3.Enable = 'off';
                app.DiameterEdit_3.Enable = 'off';
                
                app.StructureEdit_4.Enable = 'off';
                app.PropellantEdit_4.Enable = 'off';
                app.ThrustEdit_4.Enable = 'off';
                app.IspEdit_4.Enable = 'off';
                app.BurnoutEdit_4.Enable = 'off';
                app.AreaEdit_4.Enable = 'off';
                app.DiameterEdit_4.Enable = 'off';
                
                app.BoostersCheckBox.Value = 0;
                app.BoostersCheckBoxValueChanged;
                case 2
                app.StructureEdit_2.Enable = 'on';
                app.PropellantEdit_2.Enable = 'on';
                app.ThrustEdit_2.Enable = 'on';
                app.IspEdit_2.Enable = 'on';
                app.BurnoutEdit_2.Enable = 'on';
                app.AreaEdit_2.Enable = 'on';
                app.DiameterEdit_2.Enable = 'on';
                
                app.StructureEdit_3.Enable = 'off';
                app.PropellantEdit_3.Enable = 'off';
                app.ThrustEdit_3.Enable = 'off';
                app.IspEdit_3.Enable = 'off';
                app.BurnoutEdit_3.Enable = 'off';
                app.AreaEdit_3.Enable = 'off';
                app.DiameterEdit_3.Enable = 'off';
                
                app.StructureEdit_4.Enable = 'off';
                app.PropellantEdit_4.Enable = 'off';
                app.ThrustEdit_4.Enable = 'off';
                app.IspEdit_4.Enable = 'off';
                app.BurnoutEdit_4.Enable = 'off';
                app.AreaEdit_4.Enable = 'off';
                app.DiameterEdit_4.Enable = 'off';
                case 3
                app.StructureEdit_2.Enable = 'on';
                app.PropellantEdit_2.Enable = 'on';
                app.ThrustEdit_2.Enable = 'on';
                app.IspEdit_2.Enable = 'on';
                app.BurnoutEdit_2.Enable = 'on';
                app.AreaEdit_2.Enable = 'on';
                app.DiameterEdit_2.Enable = 'on';
                
                app.StructureEdit_3.Enable = 'on';
                app.PropellantEdit_3.Enable = 'on';
                app.ThrustEdit_3.Enable = 'on';
                app.IspEdit_3.Enable = 'on';
                app.BurnoutEdit_3.Enable = 'on';
                app.AreaEdit_3.Enable = 'on';
                app.DiameterEdit_3.Enable = 'on';
                
                app.StructureEdit_4.Enable = 'off';
                app.PropellantEdit_4.Enable = 'off';
                app.ThrustEdit_4.Enable = 'off';
                app.IspEdit_4.Enable = 'off';
                app.BurnoutEdit_4.Enable = 'off';
                app.AreaEdit_4.Enable = 'off';
                app.DiameterEdit_4.Enable = 'off';
                case 4
                app.StructureEdit_2.Enable = 'on';
                app.PropellantEdit_2.Enable = 'on';
                app.ThrustEdit_2.Enable = 'on';
                app.IspEdit_2.Enable = 'on';
                app.BurnoutEdit_2.Enable = 'on';
                app.AreaEdit_2.Enable = 'on';
                app.DiameterEdit_2.Enable = 'on';
                
                app.StructureEdit_3.Enable = 'on';
                app.PropellantEdit_3.Enable = 'on';
                app.ThrustEdit_3.Enable = 'on';
                app.IspEdit_3.Enable = 'on';
                app.BurnoutEdit_3.Enable = 'on';
                app.AreaEdit_3.Enable = 'on';
                app.DiameterEdit_3.Enable = 'on';
                
                app.StructureEdit_4.Enable = 'on';
                app.PropellantEdit_4.Enable = 'on';
                app.ThrustEdit_4.Enable = 'on';
                app.IspEdit_4.Enable = 'on';
                app.BurnoutEdit_4.Enable = 'on';
                app.AreaEdit_4.Enable = 'on';
                app.DiameterEdit_4.Enable = 'on';
            end
        end

        % Menu selected function: LongMarch2CMenu
        function LongMarch2CMenuSelected(app, event)
            % launchparams [m_struct_1,m_prop_1,m_struct_2,m_prop_2,Isp_1,Isp_2,T_1,T_2,t_burn_1,t_burn_2, A_e_1, A_e_2, S_1, S_2]
            app.launchparams = [8600,143000, 3200,35000, 260.69, 298, 2961.6, 741.4, 128, 130, 1.733037256, 0.2, 8.814130889, 8.814130889];
            app.StructureEdit_1.Value = app.launchparams(1); app.PropellantEdit_1.Value = app.launchparams(2); 
            app.StructureEdit_2.Value = app.launchparams(3); app.PropellantEdit_2.Value = app.launchparams(4); 
            app.IspEdit_1.Value = app.launchparams(5); app.IspEdit_2.Value = app.launchparams(6); 
            app.ThrustEdit_1.Value = app.launchparams(7); app.ThrustEdit_2.Value = app.launchparams(8); 
            app.BurnoutEdit_1.Value = app.launchparams(9); app.BurnoutEdit_2.Value = app.launchparams(10); 
            app.AreaEdit_1.Value = app.launchparams(11); app.AreaEdit_2.Value = app.launchparams(12);
            app.DiameterEdit_1.Value = app.launchparams(13); app.DiameterEdit_2.Value = app.launchparams(14);
            app.Rocket_Parameters.Value = sprintf('Stage 1 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m \n Stage 2 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m', app.launchparams(1), app.launchparams(3), app.launchparams(5), app.launchparams(7), app.launchparams(9), app.launchparams(13), app.launchparams(2), app.launchparams(4), app.launchparams(6), app.launchparams(8), app.launchparams(10), app.launchparams(14));
        end

        % Menu selected function: LongMarch2DMenu
        function LongMarch2DMenuSelected(app, event)
            % launchparams [m_struct_1,m_prop_1,m_struct_2,m_prop_2,Isp_1,Isp_2,T_1,T_2,t_burn_1,t_burn_2, A_e_1, A_e_2, S_1, S_2]
            app.launchparams = [9820, 183038, 3122, 34736, 260.0276, 300, 2961.6, 742.04, 170, 135, 1.733037256, 0.2, 8.814130889, 8.814130889];
            app.StructureEdit_1.Value = app.launchparams(1); app.PropellantEdit_1.Value = app.launchparams(2); 
            app.StructureEdit_2.Value = app.launchparams(3); app.PropellantEdit_2.Value = app.launchparams(4); 
            app.IspEdit_1.Value = app.launchparams(5); app.IspEdit_2.Value = app.launchparams(6); 
            app.ThrustEdit_1.Value = app.launchparams(7); app.ThrustEdit_2.Value = app.launchparams(8); 
            app.BurnoutEdit_1.Value = app.launchparams(9); app.BurnoutEdit_2.Value = app.launchparams(10); 
            app.AreaEdit_1.Value = app.launchparams(11); app.AreaEdit_2.Value = app.launchparams(12);
            app.DiameterEdit_1.Value = app.launchparams(13); app.DiameterEdit_2.Value = app.launchparams(14);
            app.Rocket_Parameters.Value = sprintf('Stage 1 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m \n Stage 2 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m', app.launchparams(1), app.launchparams(3), app.launchparams(5), app.launchparams(7), app.launchparams(9), app.launchparams(13), app.launchparams(2), app.launchparams(4), app.launchparams(6), app.launchparams(8), app.launchparams(10), app.launchparams(14));
        end

        % Menu selected function: Safir1BIRMenu
        function Safir1BIRMenuSelected(app, event)
            % launchparams [m_struct_1,m_prop_1,m_struct_2,m_prop_2,Isp_1,Isp_2,T_1,T_2,t_burn_1,t_burn_2, A_e_1, A_e_2, S_1, S_2]
            app.launchparams = [3500, 18200, 350, 3050, 289, 290, 51.00127656, 33.87765571, 150, 245, 0.5, 0.2, 1.22718463, 1.22718463];
            app.StructureEdit_1.Value = app.launchparams(1); app.PropellantEdit_1.Value = app.launchparams(2); 
            app.StructureEdit_2.Value = app.launchparams(3); app.PropellantEdit_2.Value = app.launchparams(4); 
            app.IspEdit_1.Value = app.launchparams(5); app.IspEdit_2.Value = app.launchparams(6); 
            app.ThrustEdit_1.Value = app.launchparams(7); app.ThrustEdit_2.Value = app.launchparams(8); 
            app.BurnoutEdit_1.Value = app.launchparams(9); app.BurnoutEdit_2.Value = app.launchparams(10); 
            app.AreaEdit_1.Value = app.launchparams(11); app.AreaEdit_2.Value = app.launchparams(12);
            app.DiameterEdit_1.Value = app.launchparams(13); app.DiameterEdit_2.Value = app.launchparams(14);
            app.Rocket_Parameters.Value = sprintf('Stage 1 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m \n Stage 2 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m', app.launchparams(1), app.launchparams(3), app.launchparams(5), app.launchparams(7), app.launchparams(9), app.launchparams(13), app.launchparams(2), app.launchparams(4), app.launchparams(6), app.launchparams(8), app.launchparams(10), app.launchparams(14));
        end

        % Menu selected function: ElectronNZMenu
        function ElectronNZMenuSelected(app, event)
            % launchparams [m_struct_1,m_prop_1,m_struct_2,m_prop_2,Isp_1,Isp_2,T_1,T_2,t_burn_1,t_burn_2, A_e_1, A_e_2, S_1, S_2]
            app.launchparams = [1000, 9250, 250, 2050, 303, 333, 192, 22, 150, 270, 0.29607698, 0.2, 0.942477796, 0.942477796];
            app.StructureEdit_1.Value = app.launchparams(1); app.PropellantEdit_1.Value = app.launchparams(2); 
            app.StructureEdit_2.Value = app.launchparams(3); app.PropellantEdit_2.Value = app.launchparams(4); 
            app.IspEdit_1.Value = app.launchparams(5); app.IspEdit_2.Value = app.launchparams(6); 
            app.ThrustEdit_1.Value = app.launchparams(7); app.ThrustEdit_2.Value = app.launchparams(8); 
            app.BurnoutEdit_1.Value = app.launchparams(9); app.BurnoutEdit_2.Value = app.launchparams(10); 
            app.AreaEdit_1.Value = app.launchparams(11); app.AreaEdit_2.Value = app.launchparams(12);
            app.DiameterEdit_1.Value = app.launchparams(13); app.DiameterEdit_2.Value = app.launchparams(14);
            app.Rocket_Parameters.Value = sprintf('Stage 1 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m \n Stage 2 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m', app.launchparams(1), app.launchparams(3), app.launchparams(5), app.launchparams(7), app.launchparams(9), app.launchparams(13), app.launchparams(2), app.launchparams(4), app.launchparams(6), app.launchparams(8), app.launchparams(10), app.launchparams(14));
        end

        % Menu selected function: StrelaMenu
        function StrelaMenuSelected(app, event)
            % launchparams [m_struct_1,m_prop_1,m_struct_2,m_prop_2,Isp_1,Isp_2,T_1,T_2,t_burn_1,t_burn_2, A_e_1, A_e_2, S_1, S_2]
            app.launchparams = [5700, 71500, 1500, 10700, 310, 320, 2080, 240, 121, 183, 1.973846533, 0.2, 4.90873852, 4.90873852];
            app.StructureEdit_1.Value = app.launchparams(1); app.PropellantEdit_1.Value = app.launchparams(2); 
            app.StructureEdit_2.Value = app.launchparams(3); app.PropellantEdit_2.Value = app.launchparams(4); 
            app.IspEdit_1.Value = app.launchparams(5); app.IspEdit_2.Value = app.launchparams(6); 
            app.ThrustEdit_1.Value = app.launchparams(7); app.ThrustEdit_2.Value = app.launchparams(8); 
            app.BurnoutEdit_1.Value = app.launchparams(9); app.BurnoutEdit_2.Value = app.launchparams(10); 
            app.AreaEdit_1.Value = app.launchparams(11); app.AreaEdit_2.Value = app.launchparams(12);
            app.DiameterEdit_1.Value = app.launchparams(13); app.DiameterEdit_2.Value = app.launchparams(14);
            app.Rocket_Parameters.Value = sprintf('Stage 1 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m \n Stage 2 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m', app.launchparams(1), app.launchparams(3), app.launchparams(5), app.launchparams(7), app.launchparams(9), app.launchparams(13), app.launchparams(2), app.launchparams(4), app.launchparams(6), app.launchparams(8), app.launchparams(10), app.launchparams(14));
        end

        % Menu selected function: Kosmos3MMenu
        function Kosmos3MMenuSelected(app, event)
            % launchparams [m_struct_1,m_prop_1,m_struct_2,m_prop_2,Isp_1,Isp_2,T_1,T_2,t_burn_1,t_burn_2, A_e_1, A_e_2, S_1, S_2]
            app.launchparams = [5340,81160, 1720, 17180, 291, 303, 1485.6, 157.3, 130, 350, 2.56477897853, 0.2, 4.90873852, 4.90873852];
            app.StructureEdit_1.Value = app.launchparams(1); app.PropellantEdit_1.Value = app.launchparams(2); 
            app.StructureEdit_2.Value = app.launchparams(3); app.PropellantEdit_2.Value = app.launchparams(4); 
            app.IspEdit_1.Value = app.launchparams(5); app.IspEdit_2.Value = app.launchparams(6); 
            app.ThrustEdit_1.Value = app.launchparams(7); app.ThrustEdit_2.Value = app.launchparams(8); 
            app.BurnoutEdit_1.Value = app.launchparams(9); app.BurnoutEdit_2.Value = app.launchparams(10); 
            app.AreaEdit_1.Value = app.launchparams(11); app.AreaEdit_2.Value = app.launchparams(12);
            app.DiameterEdit_1.Value = app.launchparams(13); app.DiameterEdit_2.Value = app.launchparams(14);
            app.Rocket_Parameters.Value = sprintf('Stage 1 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m \n Stage 2 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m', app.launchparams(1), app.launchparams(3), app.launchparams(5), app.launchparams(7), app.launchparams(9), app.launchparams(13), app.launchparams(2), app.launchparams(4), app.launchparams(6), app.launchparams(8), app.launchparams(10), app.launchparams(14));
        end

        % Menu selected function: Cyclone4MUAMenu
        function Cyclone4MUAMenuSelected(app, event)
            % launchparams [m_struct_1,m_prop_1,m_struct_2,m_prop_2,Isp_1,Isp_2,T_1,T_2,t_burn_1,t_burn_2, A_e_1, A_e_2, S_1, S_2]
            app.launchparams = [35900, 224800, 3300, 10700, 332, 325, 3498, 77.63, 200, 450, 3.631877622, 0.2, 12.4410211, 12.4410211];
            app.StructureEdit_1.Value = app.launchparams(1); app.PropellantEdit_1.Value = app.launchparams(2); 
            app.StructureEdit_2.Value = app.launchparams(3); app.PropellantEdit_2.Value = app.launchparams(4); 
            app.IspEdit_1.Value = app.launchparams(5); app.IspEdit_2.Value = app.launchparams(6); 
            app.ThrustEdit_1.Value = app.launchparams(7); app.ThrustEdit_2.Value = app.launchparams(8); 
            app.BurnoutEdit_1.Value = app.launchparams(9); app.BurnoutEdit_2.Value = app.launchparams(10); 
            app.AreaEdit_1.Value = app.launchparams(11); app.AreaEdit_2.Value = app.launchparams(12);
            app.DiameterEdit_1.Value = app.launchparams(13); app.DiameterEdit_2.Value = app.launchparams(14);
            app.Rocket_Parameters.Value = sprintf('Stage 1 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m \n Stage 2 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m', app.launchparams(1), app.launchparams(3), app.launchparams(5), app.launchparams(7), app.launchparams(9), app.launchparams(13), app.launchparams(2), app.launchparams(4), app.launchparams(6), app.launchparams(8), app.launchparams(10), app.launchparams(14));
        end

        % Menu selected function: Falcon1Menu
        function Falcon1MenuSelected(app, event)
            % launchparams [m_struct_1,m_prop_1,m_struct_2,m_prop_2,Isp_1,Isp_2,T_1,T_2,t_burn_1,t_burn_2, A_e_1, A_e_2, S_1, S_2]
            app.launchparams = [1360.77711, 21491.2065, 544.310844, 4036.97209, 255, 327, 480, 31, 169, 378, 0.592154, 0.2, 2.26980069, 2.26980069];
            app.StructureEdit_1.Value = app.launchparams(1); app.PropellantEdit_1.Value = app.launchparams(2); 
            app.StructureEdit_2.Value = app.launchparams(3); app.PropellantEdit_2.Value = app.launchparams(4); 
            app.IspEdit_1.Value = app.launchparams(5); app.IspEdit_2.Value = app.launchparams(6); 
            app.ThrustEdit_1.Value = app.launchparams(7); app.ThrustEdit_2.Value = app.launchparams(8); 
            app.BurnoutEdit_1.Value = app.launchparams(9); app.BurnoutEdit_2.Value = app.launchparams(10); 
            app.AreaEdit_1.Value = app.launchparams(11); app.AreaEdit_2.Value = app.launchparams(12);
            app.DiameterEdit_1.Value = app.launchparams(13); app.DiameterEdit_2.Value = app.launchparams(14);
            app.Rocket_Parameters.Value = sprintf('Stage 1 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m \n Stage 2 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m', app.launchparams(1), app.launchparams(3), app.launchparams(5), app.launchparams(7), app.launchparams(9), app.launchparams(13), app.launchparams(2), app.launchparams(4), app.launchparams(6), app.launchparams(8), app.launchparams(10), app.launchparams(14));
        end

        % Menu selected function: Falcon9v10Menu
        function Falcon9v10MenuSelected(app, event)
            % launchparams [m_struct_1,m_prop_1,m_struct_2,m_prop_2,Isp_1,Isp_2,T_1,T_2,t_burn_1,t_burn_2, A_e_1, A_e_2, S_1, S_2]
            app.launchparams = [28000, 411000, 4700, 73400, 311, 340, 6672, 716, 180, 375, 7.767086, 0.2, 21.7548765, 10.5208796];
            app.StructureEdit_1.Value = app.launchparams(1); app.PropellantEdit_1.Value = app.launchparams(2); 
            app.StructureEdit_2.Value = app.launchparams(3); app.PropellantEdit_2.Value = app.launchparams(4); 
            app.IspEdit_1.Value = app.launchparams(5); app.IspEdit_2.Value = app.launchparams(6); 
            app.ThrustEdit_1.Value = app.launchparams(7); app.ThrustEdit_2.Value = app.launchparams(8); 
            app.BurnoutEdit_1.Value = app.launchparams(9); app.BurnoutEdit_2.Value = app.launchparams(10); 
            app.AreaEdit_1.Value = app.launchparams(11); app.AreaEdit_2.Value = app.launchparams(12);
            app.DiameterEdit_1.Value = app.launchparams(13); app.DiameterEdit_2.Value = app.launchparams(14);
            app.Rocket_Parameters.Value = sprintf('Stage 1 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m \n Stage 2 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m', app.launchparams(1), app.launchparams(3), app.launchparams(5), app.launchparams(7), app.launchparams(9), app.launchparams(13), app.launchparams(2), app.launchparams(4), app.launchparams(6), app.launchparams(8), app.launchparams(10), app.launchparams(14));
        end

        % Menu selected function: Falcon9v11Menu
        function Falcon9v11MenuSelected(app, event)
            % launchparams [m_struct_1,m_prop_1,m_struct_2,m_prop_2,Isp_1,Isp_2,T_1,T_2,t_burn_1,t_burn_2, A_e_1, A_e_2, S_1, S_2]
            app.launchparams = [23900, 410900, 4000, 107500, 311, 348, 8227, 934, 162, 397, 6.118924, 0.2, 21.7548765, 10.5208796];
            app.StructureEdit_1.Value = app.launchparams(1); app.PropellantEdit_1.Value = app.launchparams(2); 
            app.StructureEdit_2.Value = app.launchparams(3); app.PropellantEdit_2.Value = app.launchparams(4); 
            app.IspEdit_1.Value = app.launchparams(5); app.IspEdit_2.Value = app.launchparams(6); 
            app.ThrustEdit_1.Value = app.launchparams(7); app.ThrustEdit_2.Value = app.launchparams(8); 
            app.BurnoutEdit_1.Value = app.launchparams(9); app.BurnoutEdit_2.Value = app.launchparams(10); 
            app.AreaEdit_1.Value = app.launchparams(11); app.AreaEdit_2.Value = app.launchparams(12);
            app.DiameterEdit_1.Value = app.launchparams(13); app.DiameterEdit_2.Value = app.launchparams(14);
            app.Rocket_Parameters.Value = sprintf('Stage 1 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m \n Stage 2 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m', app.launchparams(1), app.launchparams(3), app.launchparams(5), app.launchparams(7), app.launchparams(9), app.launchparams(13), app.launchparams(2), app.launchparams(4), app.launchparams(6), app.launchparams(8), app.launchparams(10), app.launchparams(14));
        end

        % Menu selected function: Falcon9FTMenu
        function Falcon9FTMenuSelected(app, event)
            % launchparams [m_struct_1,m_prop_1,m_struct_2,m_prop_2,Isp_1,Isp_2,T_1,T_2,t_burn_1,t_burn_2, A_e_1, A_e_2, S_1, S_2]
            app.launchparams = [27200, 418700, 4500, 111500, 312, 348, 7607, 934, 156, 343, 6.9775475, 0.2, 10.5208796, 10.5208796];
            app.StructureEdit_1.Value = app.launchparams(1); app.PropellantEdit_1.Value = app.launchparams(2); 
            app.StructureEdit_2.Value = app.launchparams(3); app.PropellantEdit_2.Value = app.launchparams(4); 
            app.IspEdit_1.Value = app.launchparams(5); app.IspEdit_2.Value = app.launchparams(6); 
            app.ThrustEdit_1.Value = app.launchparams(7); app.ThrustEdit_2.Value = app.launchparams(8); 
            app.BurnoutEdit_1.Value = app.launchparams(9); app.BurnoutEdit_2.Value = app.launchparams(10); 
            app.AreaEdit_1.Value = app.launchparams(11); app.AreaEdit_2.Value = app.launchparams(12);
            app.DiameterEdit_1.Value = app.launchparams(13); app.DiameterEdit_2.Value = app.launchparams(14);
            app.Rocket_Parameters.Value = sprintf('Stage 1 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m \n Stage 2 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m', app.launchparams(1), app.launchparams(3), app.launchparams(5), app.launchparams(7), app.launchparams(9), app.launchparams(13), app.launchparams(2), app.launchparams(4), app.launchparams(6), app.launchparams(8), app.launchparams(10), app.launchparams(14));
        end

        % Menu selected function: Falcon9Block5Menu
        function Falcon9Block5MenuSelected(app, event)
            % launchparams [m_struct_1,m_prop_1,m_struct_2,m_prop_2,Isp_1,Isp_2,T_1,T_2,t_burn_1,t_burn_2, A_e_1, A_e_2, S_1, S_2]
            app.launchparams = [23900, 410900, 4000, 107500, 311, 348, 8227, 934, 162, 397, 6.118924, 0.2, 21.7548765, 10.5208796];
            app.StructureEdit_1.Value = app.launchparams(1); app.PropellantEdit_1.Value = app.launchparams(2); 
            app.StructureEdit_2.Value = app.launchparams(3); app.PropellantEdit_2.Value = app.launchparams(4); 
            app.IspEdit_1.Value = app.launchparams(5); app.IspEdit_2.Value = app.launchparams(6); 
            app.ThrustEdit_1.Value = app.launchparams(7); app.ThrustEdit_2.Value = app.launchparams(8); 
            app.BurnoutEdit_1.Value = app.launchparams(9); app.BurnoutEdit_2.Value = app.launchparams(10); 
            app.AreaEdit_1.Value = app.launchparams(11); app.AreaEdit_2.Value = app.launchparams(12);
            app.DiameterEdit_1.Value = app.launchparams(13); app.DiameterEdit_2.Value = app.launchparams(14);
            app.Rocket_Parameters.Value = sprintf('Stage 1 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m \n Stage 2 \n Structural Mass: %.i kg \n Propellant Mass: %.i kg \n Specific Impulse: %.i s \n Thrust: %.i kN \n Burnout Time: %.i s \n Stage Diameter: %.3f m', app.launchparams(1), app.launchparams(3), app.launchparams(5), app.launchparams(7), app.launchparams(9), app.launchparams(13), app.launchparams(2), app.launchparams(4), app.launchparams(6), app.launchparams(8), app.launchparams(10), app.launchparams(14));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.SpaceportsMenu = uimenu(app.UIFigure);
            app.SpaceportsMenu.Text = 'Spaceports';
            app.AsiaMenu = uimenu(app.SpaceportsMenu);
            app.AsiaMenu.Text = 'Asia';
            app.JiuquanSLCCNMenu = uimenu(app.AsiaMenu);
            app.JiuquanSLCCNMenu.MenuSelectedFcn = createCallbackFcn(app, @JiuquanSLCCNMenuSelected, true);
            app.JiuquanSLCCNMenu.Text = 'Jiuquan SLC, CN';
            app.TaiyuanSLCCNMenu = uimenu(app.AsiaMenu);
            app.TaiyuanSLCCNMenu.MenuSelectedFcn = createCallbackFcn(app, @TaiyuanSLCCNMenuSelected, true);
            app.TaiyuanSLCCNMenu.Tooltip = {'Used for manned missions'};
            app.TaiyuanSLCCNMenu.Text = 'Taiyuan SLC, CN';
            app.XichangSLCCNMenu = uimenu(app.AsiaMenu);
            app.XichangSLCCNMenu.MenuSelectedFcn = createCallbackFcn(app, @XichangSLCCNMenuSelected, true);
            app.XichangSLCCNMenu.Tooltip = {'Used for GEO, Lunar missions'};
            app.XichangSLCCNMenu.Text = 'Xichang SLC, CN';
            app.WenchangSLCCNMenu = uimenu(app.AsiaMenu);
            app.WenchangSLCCNMenu.MenuSelectedFcn = createCallbackFcn(app, @WenchangSLCCNMenuSelected, true);
            app.WenchangSLCCNMenu.Tooltip = {'Newest site for heavy launch systems'};
            app.WenchangSLCCNMenu.Text = 'Wenchang SLC, CN';
            app.SriharikotaINMenu = uimenu(app.AsiaMenu);
            app.SriharikotaINMenu.MenuSelectedFcn = createCallbackFcn(app, @SriharikotaINMenuSelected, true);
            app.SriharikotaINMenu.Tooltip = {'Used for GEO, Lunar missions'};
            app.SriharikotaINMenu.Text = 'Sriharikota, IN';
            app.SemnanIRMenu = uimenu(app.AsiaMenu);
            app.SemnanIRMenu.MenuSelectedFcn = createCallbackFcn(app, @SemnanIRMenuSelected, true);
            app.SemnanIRMenu.Tooltip = {'Main Iranian spaceport'};
            app.SemnanIRMenu.Text = 'Semnan, IR';
            app.PalmachimISMenu = uimenu(app.AsiaMenu);
            app.PalmachimISMenu.MenuSelectedFcn = createCallbackFcn(app, @PalmachimISMenuSelected, true);
            app.PalmachimISMenu.Tooltip = {'Used for retrograde launches'};
            app.PalmachimISMenu.Text = 'Palmachim, IS';
            app.TanegashimaSCJPMenu = uimenu(app.AsiaMenu);
            app.TanegashimaSCJPMenu.MenuSelectedFcn = createCallbackFcn(app, @TanegashimaSCJPMenuSelected, true);
            app.TanegashimaSCJPMenu.Tooltip = {'Main Spaceport of Japan'};
            app.TanegashimaSCJPMenu.Text = 'Tanegashima SC, JP';
            app.BaikonurCosmodromeKZMenu = uimenu(app.AsiaMenu);
            app.BaikonurCosmodromeKZMenu.MenuSelectedFcn = createCallbackFcn(app, @BaikonurCosmodromeKZMenuSelected, true);
            app.BaikonurCosmodromeKZMenu.Tooltip = {'First manned flight launch site'};
            app.BaikonurCosmodromeKZMenu.Text = 'Baikonur Cosmodrome, KZ';
            app.SohaeNKMenu = uimenu(app.AsiaMenu);
            app.SohaeNKMenu.MenuSelectedFcn = createCallbackFcn(app, @SohaeNKMenuSelected, true);
            app.SohaeNKMenu.Tooltip = {'North Korean launch site'};
            app.SohaeNKMenu.Text = 'Sohae, NK';
            app.NaroSCSKMenu = uimenu(app.AsiaMenu);
            app.NaroSCSKMenu.MenuSelectedFcn = createCallbackFcn(app, @NaroSCSKMenuSelected, true);
            app.NaroSCSKMenu.Tooltip = {'First space center in South Korea'};
            app.NaroSCSKMenu.Text = 'Naro SC, SK';
            app.YasnyCosmodromeRUMenu = uimenu(app.AsiaMenu);
            app.YasnyCosmodromeRUMenu.MenuSelectedFcn = createCallbackFcn(app, @YasnyCosmodromeRUMenuSelected, true);
            app.YasnyCosmodromeRUMenu.Tooltip = {'Used by converted ICBMs'};
            app.YasnyCosmodromeRUMenu.Text = 'Yasny Cosmodrome, RU';
            app.VostochnyCosmodromeRUMenu = uimenu(app.AsiaMenu);
            app.VostochnyCosmodromeRUMenu.MenuSelectedFcn = createCallbackFcn(app, @VostochnyCosmodromeRUMenuSelected, true);
            app.VostochnyCosmodromeRUMenu.Tooltip = {'First space center of post-Soviet Russia'};
            app.VostochnyCosmodromeRUMenu.Text = 'Vostochny Cosmodrome, RU';
            app.EuropeMenu = uimenu(app.SpaceportsMenu);
            app.EuropeMenu.Text = 'Europe';
            app.AndyaSCNOMenu = uimenu(app.EuropeMenu);
            app.AndyaSCNOMenu.MenuSelectedFcn = createCallbackFcn(app, @AndyaSCNOMenuSelected, true);
            app.AndyaSCNOMenu.Tooltip = {'For upper atmospheric launches'};
            app.AndyaSCNOMenu.Text = 'Andøya SC, NO';
            app.SvalRakNOMenu = uimenu(app.EuropeMenu);
            app.SvalRakNOMenu.MenuSelectedFcn = createCallbackFcn(app, @SvalRakNOMenuSelected, true);
            app.SvalRakNOMenu.Tooltip = {'The northernmost spaceport'};
            app.SvalRakNOMenu.Text = 'SvalRak, NO';
            app.AzoresPRMenu = uimenu(app.EuropeMenu);
            app.AzoresPRMenu.MenuSelectedFcn = createCallbackFcn(app, @AzoresPRMenuSelected, true);
            app.AzoresPRMenu.Tooltip = {'Currently under development, for all orbit types'};
            app.AzoresPRMenu.Text = 'Azores, PR';
            app.KapustinYarRUMenu = uimenu(app.EuropeMenu);
            app.KapustinYarRUMenu.MenuSelectedFcn = createCallbackFcn(app, @KapustinYarRUMenuSelected, true);
            app.KapustinYarRUMenu.Tooltip = {'Military test site'};
            app.KapustinYarRUMenu.Text = 'Kapustin Yar, RU';
            app.PlesetskRUMenu = uimenu(app.EuropeMenu);
            app.PlesetskRUMenu.MenuSelectedFcn = createCallbackFcn(app, @PlesetskRUMenuSelected, true);
            app.PlesetskRUMenu.Tooltip = {'Main Russian-owned cosmodrome'};
            app.PlesetskRUMenu.Text = 'Plesetsk, RU';
            app.ElArenosilloSPMenu = uimenu(app.EuropeMenu);
            app.ElArenosilloSPMenu.MenuSelectedFcn = createCallbackFcn(app, @ElArenosilloSPMenuSelected, true);
            app.ElArenosilloSPMenu.Tooltip = {'For suborbital rockets'};
            app.ElArenosilloSPMenu.Text = 'El Arenosillo, SP';
            app.SutherlandGBMenu = uimenu(app.EuropeMenu);
            app.SutherlandGBMenu.MenuSelectedFcn = createCallbackFcn(app, @SutherlandGBMenuSelected, true);
            app.SutherlandGBMenu.Tooltip = {'Planned space center'};
            app.SutherlandGBMenu.Text = 'Sutherland, GB';
            app.AmericasMenu = uimenu(app.SpaceportsMenu);
            app.AmericasMenu.Text = 'Americas';
            app.KourouFRGFMenu = uimenu(app.AmericasMenu);
            app.KourouFRGFMenu.MenuSelectedFcn = createCallbackFcn(app, @KourouFRGFMenuSelected, true);
            app.KourouFRGFMenu.Tooltip = {'Main spaceport of ESA'};
            app.KourouFRGFMenu.Text = 'Kourou, FR-GF';
            app.PuertoBelgranoARMenu = uimenu(app.AmericasMenu);
            app.PuertoBelgranoARMenu.MenuSelectedFcn = createCallbackFcn(app, @PuertoBelgranoARMenuSelected, true);
            app.PuertoBelgranoARMenu.Tooltip = {'Currently under construction'};
            app.PuertoBelgranoARMenu.Text = 'Puerto Belgrano, AR';
            app.AlcntaraSCBRMenu = uimenu(app.AmericasMenu);
            app.AlcntaraSCBRMenu.MenuSelectedFcn = createCallbackFcn(app, @AlcntaraSCBRMenuSelected, true);
            app.AlcntaraSCBRMenu.Tooltip = {'Closest spaceport to equator'};
            app.AlcntaraSCBRMenu.Text = 'Alcântara SC, BR';
            app.WallopsUSMenu = uimenu(app.AmericasMenu);
            app.WallopsUSMenu.MenuSelectedFcn = createCallbackFcn(app, @WallopsUSMenuSelected, true);
            app.WallopsUSMenu.Tooltip = {'Operated by Goddard SC, Rocket Lab launch site'};
            app.WallopsUSMenu.Text = 'Wallops, US';
            app.WhiteSandsUSMenu = uimenu(app.AmericasMenu);
            app.WhiteSandsUSMenu.MenuSelectedFcn = createCallbackFcn(app, @WhiteSandsUSMenuSelected, true);
            app.WhiteSandsUSMenu.Tooltip = {'Missile test range, landing pad for Space Shuttle'};
            app.WhiteSandsUSMenu.Text = 'White Sands, US';
            app.KennedySCUSMenu = uimenu(app.AmericasMenu);
            app.KennedySCUSMenu.MenuSelectedFcn = createCallbackFcn(app, @KennedySCUSMenuSelected, true);
            app.KennedySCUSMenu.Tooltip = {'Main US and SpaceX launch site'};
            app.KennedySCUSMenu.Text = 'Kennedy SC, US';
            app.VandenbergUSMenu = uimenu(app.AmericasMenu);
            app.VandenbergUSMenu.MenuSelectedFcn = createCallbackFcn(app, @VandenbergUSMenuSelected, true);
            app.VandenbergUSMenu.Tooltip = {'West Coast launch site for US and SpaceX'};
            app.VandenbergUSMenu.Text = 'Vandenberg, US';
            app.KodiakUSMenu = uimenu(app.AmericasMenu);
            app.KodiakUSMenu.MenuSelectedFcn = createCallbackFcn(app, @KodiakUSMenuSelected, true);
            app.KodiakUSMenu.Tooltip = {'Located in Alaska, suitable for polar launches'};
            app.KodiakUSMenu.Text = 'Kodiak, US';
            app.TexasLSUSMenu = uimenu(app.AmericasMenu);
            app.TexasLSUSMenu.MenuSelectedFcn = createCallbackFcn(app, @TexasLSUSMenuSelected, true);
            app.TexasLSUSMenu.Tooltip = {'Currently under construction by SpaceX'};
            app.TexasLSUSMenu.Text = 'Texas LS, US';
            app.OceaniaMenu = uimenu(app.SpaceportsMenu);
            app.OceaniaMenu.Text = 'Oceania';
            app.MahiaNZMenu = uimenu(app.OceaniaMenu);
            app.MahiaNZMenu.MenuSelectedFcn = createCallbackFcn(app, @MahiaNZMenuSelected, true);
            app.MahiaNZMenu.Tooltip = {'Operated by Rocket Lab'};
            app.MahiaNZMenu.Text = 'Mahia, NZ';
            app.WhalersWayAUMenu = uimenu(app.OceaniaMenu);
            app.WhalersWayAUMenu.MenuSelectedFcn = createCallbackFcn(app, @WhalersWayAUMenuSelected, true);
            app.WhalersWayAUMenu.Tooltip = {'Currently on stage of proposal'};
            app.WhalersWayAUMenu.Text = 'Whalers Way, AU';
            app.OthersMenu = uimenu(app.SpaceportsMenu);
            app.OthersMenu.Text = 'Others';
            app.SeaLaunchMenu = uimenu(app.OthersMenu);
            app.SeaLaunchMenu.MenuSelectedFcn = createCallbackFcn(app, @SeaLaunchMenuSelected, true);
            app.SeaLaunchMenu.Tooltip = {'Located on equator'};
            app.SeaLaunchMenu.Text = 'Sea Launch';
            app.MLPSputnikDKMenu = uimenu(app.OthersMenu);
            app.MLPSputnikDKMenu.MenuSelectedFcn = createCallbackFcn(app, @MLPSputnikDKMenuSelected, true);
            app.MLPSputnikDKMenu.Tooltip = {'Launch site in Northern Sea'};
            app.MLPSputnikDKMenu.Text = 'MLP Sputnik, DK';
            app.RocketsMenu = uimenu(app.UIFigure);
            app.RocketsMenu.Text = 'Rockets';
            app.RussiaMenu = uimenu(app.RocketsMenu);
            app.RussiaMenu.Text = 'Russia';
            app.StrelaMenu = uimenu(app.RussiaMenu);
            app.StrelaMenu.MenuSelectedFcn = createCallbackFcn(app, @StrelaMenuSelected, true);
            app.StrelaMenu.Text = 'Strela';
            app.Kosmos3MMenu = uimenu(app.RussiaMenu);
            app.Kosmos3MMenu.MenuSelectedFcn = createCallbackFcn(app, @Kosmos3MMenuSelected, true);
            app.Kosmos3MMenu.Text = 'Kosmos-3M';
            app.USAMenu = uimenu(app.RocketsMenu);
            app.USAMenu.Text = 'USA';
            app.Falcon1Menu = uimenu(app.USAMenu);
            app.Falcon1Menu.MenuSelectedFcn = createCallbackFcn(app, @Falcon1MenuSelected, true);
            app.Falcon1Menu.Text = 'Falcon 1';
            app.Falcon9Menu = uimenu(app.USAMenu);
            app.Falcon9Menu.Text = 'Falcon 9';
            app.Falcon9v10Menu = uimenu(app.Falcon9Menu);
            app.Falcon9v10Menu.MenuSelectedFcn = createCallbackFcn(app, @Falcon9v10MenuSelected, true);
            app.Falcon9v10Menu.Text = 'Falcon 9 v1.0';
            app.Falcon9v11Menu = uimenu(app.Falcon9Menu);
            app.Falcon9v11Menu.MenuSelectedFcn = createCallbackFcn(app, @Falcon9v11MenuSelected, true);
            app.Falcon9v11Menu.Text = 'Falcon 9 v1.1';
            app.Falcon9FTMenu = uimenu(app.Falcon9Menu);
            app.Falcon9FTMenu.MenuSelectedFcn = createCallbackFcn(app, @Falcon9FTMenuSelected, true);
            app.Falcon9FTMenu.Text = 'Falcon 9 FT';
            app.Falcon9Block5Menu = uimenu(app.Falcon9Menu);
            app.Falcon9Block5Menu.MenuSelectedFcn = createCallbackFcn(app, @Falcon9Block5MenuSelected, true);
            app.Falcon9Block5Menu.Text = 'Falcon 9 Block 5';
            app.ChinaMenu = uimenu(app.RocketsMenu);
            app.ChinaMenu.Text = 'China';
            app.LongMarch2CMenu = uimenu(app.ChinaMenu);
            app.LongMarch2CMenu.MenuSelectedFcn = createCallbackFcn(app, @LongMarch2CMenuSelected, true);
            app.LongMarch2CMenu.Text = 'Long March 2C';
            app.LongMarch2DMenu = uimenu(app.ChinaMenu);
            app.LongMarch2DMenu.MenuSelectedFcn = createCallbackFcn(app, @LongMarch2DMenuSelected, true);
            app.LongMarch2DMenu.Text = 'Long March 2D';
            app.EuropeanUnionMenu = uimenu(app.RocketsMenu);
            app.EuropeanUnionMenu.Text = 'European Union';
            app.SouthKoreaMenu = uimenu(app.RocketsMenu);
            app.SouthKoreaMenu.Text = 'South Korea';
            app.Naro1Menu = uimenu(app.SouthKoreaMenu);
            app.Naro1Menu.MenuSelectedFcn = createCallbackFcn(app, @Naro1MenuSelected, true);
            app.Naro1Menu.Text = 'Naro-1';
            app.JapanMenu = uimenu(app.RocketsMenu);
            app.JapanMenu.Text = 'Japan';
            app.OthersMenu_2 = uimenu(app.RocketsMenu);
            app.OthersMenu_2.Text = 'Others';
            app.Safir1BIRMenu = uimenu(app.OthersMenu_2);
            app.Safir1BIRMenu.MenuSelectedFcn = createCallbackFcn(app, @Safir1BIRMenuSelected, true);
            app.Safir1BIRMenu.Text = 'Safir-1B (IR)';
            app.ElectronNZMenu = uimenu(app.OthersMenu_2);
            app.ElectronNZMenu.MenuSelectedFcn = createCallbackFcn(app, @ElectronNZMenuSelected, true);
            app.ElectronNZMenu.Text = 'Electron (NZ)';
            app.Cyclone4MUAMenu = uimenu(app.OthersMenu_2);
            app.Cyclone4MUAMenu.MenuSelectedFcn = createCallbackFcn(app, @Cyclone4MUAMenuSelected, true);
            app.Cyclone4MUAMenu.Text = 'Cyclone-4M (UA)';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Launch vehicle trajectory')
            xlabel(app.UIAxes, 'X, km')
            ylabel(app.UIAxes, 'Y, km')
            app.UIAxes.XLim = [-7000 7000];
            app.UIAxes.YLim = [-7000 7000];
            app.UIAxes.ZTick = [-6000 -4000 -2000 0 2000 4000 6000];
            app.UIAxes.ZTickLabel = {'-6000'; '-4000'; '-2000'; '0'; '2000'; '4000'; '6000'};
            app.BeginSimulationButton = uibutton(app.UIFigure, 'push');
            app.BeginSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.BeginSimulationButton.Text = 'Begin Simulation';
            app.GraphTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.GraphTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GraphTypeButtonGroupSelectionChanged, true);
            app.GraphTypeButtonGroup.Title = 'Graph Type';
            app.TrajectoryButton = uitogglebutton(app.GraphTypeButtonGroup);
            app.TrajectoryButton.Enable = 'off';
            app.TrajectoryButton.Text = 'Trajectory';
            app.TrajectoryButton.Value = true;
            app.VelocityButton = uitogglebutton(app.GraphTypeButtonGroup);
            app.VelocityButton.Enable = 'off';
            app.VelocityButton.Text = 'Velocity';
            app.MassButton = uitogglebutton(app.GraphTypeButtonGroup);
            app.MassButton.Enable = 'off';
            app.MassButton.Text = 'Mass';
            app.DynamicPressureButton = uitogglebutton(app.GraphTypeButtonGroup);
            app.DynamicPressureButton.Enable = 'off';
            app.DynamicPressureButton.Text = 'Dynamic Pressure';
            app.TemperatureButton = uitogglebutton(app.GraphTypeButtonGroup);
            app.TemperatureButton.Enable = 'off';
            app.TemperatureButton.Text = 'Temperature';
            app.AirDensityButton = uitogglebutton(app.GraphTypeButtonGroup);
            app.AirDensityButton.Enable = 'off';
            app.AirDensityButton.Text = 'Air Density';
            app.GroundTrackButton = uitogglebutton(app.GraphTypeButtonGroup);
            app.GroundTrackButton.Enable = 'off';
            app.GroundTrackButton.Text = 'Ground Track';
            app.LiftoffSliderLabel = uilabel(app.UIFigure);
            app.LiftoffSliderLabel.Text = 'Liftoff';
            app.LiftoffSlider = uislider(app.UIFigure);
            app.LiftoffSlider.Limits = [0 605];
            app.LiftoffSlider.MajorTicks = [0 100 195 260 350 420 500 605];
            app.LiftoffSlider.ValueChangedFcn = createCallbackFcn(app, @LiftoffSliderValueChanged, true);
            app.LiftoffSlider.Enable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TargetOrbitTab = uitab(app.TabGroup);
            app.TargetOrbitTab.Title = 'Target Orbit';
            app.PayloadMassEditFieldLabel = uilabel(app.TargetOrbitTab);
            app.PayloadMassEditFieldLabel.Text = 'Payload Mass';
            app.PayloadMassEditField = uieditfield(app.TargetOrbitTab, 'numeric');
            app.PayloadMassEditField.Limits = [0 Inf];
            app.PayloadMassEditField.ValueDisplayFormat = '%.0f';
            app.PayloadMassEditField.Value = 400;
            app.LatitudeLabel = uilabel(app.TargetOrbitTab);
            app.LatitudeLabel.Text = 'Latitude, °';
            app.LatitudeEditField = uieditfield(app.TargetOrbitTab, 'numeric');
            app.LatitudeEditField.Limits = [-180 180];
            app.LatitudeEditField.ValueDisplayFormat = '%.3f';
            app.LatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LatitudeEditFieldValueChanged, true);
            app.LatitudeEditField.Tooltip = {'Positive for E, negative for W'};
            app.LongtitudeEditFieldLabel = uilabel(app.TargetOrbitTab);
            app.LongtitudeEditFieldLabel.Text = 'Longtitude, °';
            app.LongtitudeEditField = uieditfield(app.TargetOrbitTab, 'numeric');
            app.LongtitudeEditField.Limits = [-180 180];
            app.LongtitudeEditField.ValueDisplayFormat = '%.3f';
            app.LongtitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LongtitudeEditFieldValueChanged, true);
            app.LongtitudeEditField.Tooltip = {'Positive for E, negative for W'};
            app.OrbitinclinationLabel = uilabel(app.TargetOrbitTab);
            app.OrbitinclinationLabel.Text = 'Orbit inclination, °';
            app.OrbitinclinationEditField = uieditfield(app.TargetOrbitTab, 'numeric');
            app.OrbitinclinationEditField.Limits = [0 180];
            app.OrbitinclinationEditField.ValueDisplayFormat = '%.3f';
            app.OrbitinclinationEditField.Tooltip = {'Positive for N, negative for S'};
            app.TargetorbitEditFieldLabel = uilabel(app.TargetOrbitTab);
            app.TargetorbitEditFieldLabel.Text = 'Target orbit';
            app.TargetorbitEditField = uieditfield(app.TargetOrbitTab, 'numeric');
            app.TargetorbitEditField.Limits = [0 Inf];
            app.TargetorbitEditField.ValueDisplayFormat = '%.0f';
            app.TargetorbitEditField.Value = 500;
            app.Switch = uiswitch(app.TargetOrbitTab, 'slider');
            app.Switch.Items = {'Simulation', 'Optimization'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Simulation';
            app.TextArea = uitextarea(app.TargetOrbitTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'In order to type custom values for rocket stages, access ''Stages'' section.'; 'To change tick increment (for accuracy) or change coasting time between stages, access ''Advanced'' section.'};
            app.ApogeekmEditFieldLabel = uilabel(app.TargetOrbitTab);
            app.ApogeekmEditFieldLabel.Enable = 'off';
            app.ApogeekmEditFieldLabel.Text = 'Apogee, km';
            app.ApogeekmEditField = uieditfield(app.TargetOrbitTab, 'numeric');
            app.ApogeekmEditField.ValueDisplayFormat = '%.3f';
            app.ApogeekmEditField.Enable = 'off';
            app.PerigeekmEditFieldLabel = uilabel(app.TargetOrbitTab);
            app.PerigeekmEditFieldLabel.Enable = 'off';
            app.PerigeekmEditFieldLabel.Text = 'Perigee, km';
            app.PerigeekmEditField = uieditfield(app.TargetOrbitTab, 'numeric');
            app.PerigeekmEditField.ValueDisplayFormat = '%.3f';
            app.PerigeekmEditField.Enable = 'off';
            app.StagesTab = uitab(app.TabGroup);
            app.StagesTab.Title = 'Stages';
            app.PropellantEditFieldLabel = uilabel(app.StagesTab);
            app.PropellantEditFieldLabel.Text = 'Propellant';
            app.PropellantEdit_1 = uieditfield(app.StagesTab, 'numeric');
            app.PropellantEdit_1.Limits = [0 Inf];
            app.PropellantEdit_1.ValueDisplayFormat = '%.0f';
            app.PropellantEdit_1.Value = 39463;
            app.ThrustkNEditFieldLabel = uilabel(app.StagesTab);
            app.ThrustkNEditFieldLabel.Text = 'Thrust, kN';
            app.ThrustEdit_1 = uieditfield(app.StagesTab, 'numeric');
            app.ThrustEdit_1.Limits = [0 Inf];
            app.ThrustEdit_1.Value = 612.91563;
            app.IspEditFieldLabel = uilabel(app.StagesTab);
            app.IspEditFieldLabel.Text = 'Isp';
            app.IspEdit_1 = uieditfield(app.StagesTab, 'numeric');
            app.IspEdit_1.Limits = [0 Inf];
            app.IspEdit_1.ValueDisplayFormat = '%.0f';
            app.IspEdit_1.Value = 304;
            app.BurnouttimeEditFieldLabel = uilabel(app.StagesTab);
            app.BurnouttimeEditFieldLabel.Text = 'Burnout time';
            app.BurnoutEdit_1 = uieditfield(app.StagesTab, 'numeric');
            app.BurnoutEdit_1.Limits = [0 Inf];
            app.BurnoutEdit_1.Value = 191.948032;
            app.NozzleAreaLabel = uilabel(app.StagesTab);
            app.NozzleAreaLabel.Text = 'Nozzle Area';
            app.AreaEdit_1 = uieditfield(app.StagesTab, 'numeric');
            app.AreaEdit_1.Limits = [0 Inf];
            app.AreaEdit_1.Value = 0.9;
            app.StructureEditFieldLabel = uilabel(app.StagesTab);
            app.StructureEditFieldLabel.Text = 'Structure';
            app.StructureEdit_1 = uieditfield(app.StagesTab, 'numeric');
            app.StructureEdit_1.Limits = [0 Inf];
            app.StructureEdit_1.ValueDisplayFormat = '%.0f';
            app.StructureEdit_1.Value = 2717;
            app.StructureEdit_2 = uieditfield(app.StagesTab, 'numeric');
            app.StructureEdit_2.Value = 544;
            app.PropellantEdit_2 = uieditfield(app.StagesTab, 'numeric');
            app.PropellantEdit_2.Value = 4037;
            app.ThrustEdit_2 = uieditfield(app.StagesTab, 'numeric');
            app.ThrustEdit_2.Value = 31.38128;
            app.IspEdit_2 = uieditfield(app.StagesTab, 'numeric');
            app.IspEdit_2.Value = 325;
            app.BurnoutEdit_2 = uieditfield(app.StagesTab, 'numeric');
            app.BurnoutEdit_2.Value = 410.0078125;
            app.AreaEdit_2 = uieditfield(app.StagesTab, 'numeric');
            app.AreaEdit_2.Value = 0.2;
            app.StructureEdit_3 = uieditfield(app.StagesTab, 'numeric');
            app.StructureEdit_3.Enable = 'off';
            app.PropellantEdit_3 = uieditfield(app.StagesTab, 'numeric');
            app.PropellantEdit_3.Enable = 'off';
            app.ThrustEdit_3 = uieditfield(app.StagesTab, 'numeric');
            app.ThrustEdit_3.Enable = 'off';
            app.IspEdit_3 = uieditfield(app.StagesTab, 'numeric');
            app.IspEdit_3.Enable = 'off';
            app.BurnoutEdit_3 = uieditfield(app.StagesTab, 'numeric');
            app.BurnoutEdit_3.Enable = 'off';
            app.AreaEdit_3 = uieditfield(app.StagesTab, 'numeric');
            app.AreaEdit_3.Enable = 'off';
            app.StructureEdit_4 = uieditfield(app.StagesTab, 'numeric');
            app.StructureEdit_4.Enable = 'off';
            app.PropellantEdit_4 = uieditfield(app.StagesTab, 'numeric');
            app.PropellantEdit_4.Enable = 'off';
            app.ThrustEdit_4 = uieditfield(app.StagesTab, 'numeric');
            app.ThrustEdit_4.Enable = 'off';
            app.IspEdit_4 = uieditfield(app.StagesTab, 'numeric');
            app.IspEdit_4.Enable = 'off';
            app.BurnoutEdit_4 = uieditfield(app.StagesTab, 'numeric');
            app.BurnoutEdit_4.Enable = 'off';
            app.AreaEdit_4 = uieditfield(app.StagesTab, 'numeric');
            app.AreaEdit_4.Enable = 'off';
            app.StageAreaLabel = uilabel(app.StagesTab);
            app.StageAreaLabel.Text = 'Stage Area';
            app.DiameterEdit_1 = uieditfield(app.StagesTab, 'numeric');
            app.DiameterEdit_1.Limits = [0 Inf];
            app.DiameterEdit_1.Value = 2;
            app.DiameterEdit_2 = uieditfield(app.StagesTab, 'numeric');
            app.DiameterEdit_2.Value = 1;
            app.DiameterEdit_3 = uieditfield(app.StagesTab, 'numeric');
            app.DiameterEdit_3.Enable = 'off';
            app.DiameterEdit_4 = uieditfield(app.StagesTab, 'numeric');
            app.DiameterEdit_4.Enable = 'off';
            app.BoosterNoEdit_1 = uieditfield(app.StagesTab, 'numeric');
            app.BoosterNoEdit_1.Enable = 'off';
            app.BoosterStructureEdit_1 = uieditfield(app.StagesTab, 'numeric');
            app.BoosterStructureEdit_1.Enable = 'off';
            app.BoosterPropellantEdit_1 = uieditfield(app.StagesTab, 'numeric');
            app.BoosterPropellantEdit_1.Enable = 'off';
            app.BoosterThrustEdit_1 = uieditfield(app.StagesTab, 'numeric');
            app.BoosterThrustEdit_1.Enable = 'off';
            app.BoosterIspEdit_1 = uieditfield(app.StagesTab, 'numeric');
            app.BoosterIspEdit_1.Enable = 'off';
            app.BoosterBurnoutEdit_1 = uieditfield(app.StagesTab, 'numeric');
            app.BoosterBurnoutEdit_1.Enable = 'off';
            app.BoosterNozzleEdit_1 = uieditfield(app.StagesTab, 'numeric');
            app.BoosterNozzleEdit_1.Enable = 'off';
            app.BoostersCheckBox = uicheckbox(app.StagesTab);
            app.BoostersCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoostersCheckBoxValueChanged, true);
            app.BoostersCheckBox.Text = 'Boosters';
            app.BoosterDiameter_1 = uieditfield(app.StagesTab, 'numeric');
            app.BoosterDiameter_1.Enable = 'off';
            app.ofStagesSpinnerLabel = uilabel(app.StagesTab);
            app.ofStagesSpinnerLabel.Text = '# of Stages';
            app.ofStagesSpinner = uispinner(app.StagesTab);
            app.ofStagesSpinner.Limits = [1 4];
            app.ofStagesSpinner.ValueChangedFcn = createCallbackFcn(app, @ofStagesSpinnerValueChanged, true);
            app.ofStagesSpinner.Value = 2;
            app.PhasesTab = uitab(app.TabGroup);
            app.PhasesTab.Title = 'Phases';
            app.TimesecEditFieldLabel = uilabel(app.PhasesTab);
            app.TimesecEditFieldLabel.Text = 'Time, sec';
            app.TimesecEditField = uieditfield(app.PhasesTab, 'text');
            app.PitchAngleEditFieldLabel = uilabel(app.PhasesTab);
            app.PitchAngleEditFieldLabel.Text = 'Pitch Angle,°';
            app.PitchAngleEditField = uieditfield(app.PhasesTab, 'text');
            app.PhasepointsTextAreaLabel = uilabel(app.PhasesTab);
            app.PhasepointsTextAreaLabel.Text = 'Phase points';
            app.PhasepointsTextArea = uitextarea(app.PhasesTab);
            app.PhasepointsTextArea.Editable = 'off';
            app.TextArea_2 = uitextarea(app.PhasesTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Input values for phase time and pitch angle. Delimiters are either comma (,) or semicolon (;). For floating values, use dot.'; 'Resultant phasing will be shown in output window below'};
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.TickincrementSliderLabel = uilabel(app.AdvancedTab);
            app.TickincrementSliderLabel.Text = 'Tick increment';
            app.TickincrementSlider = uislider(app.AdvancedTab);
            app.TickincrementSlider.Limits = [0.1 1.5];
            app.TickincrementSlider.MajorTicks = [0 0.25 0.5 0.75 1 1.25 1.5];
            app.TickincrementSlider.Value = 0.1;
            app.TimeSliderLabel_2 = uilabel(app.UIFigure);
            app.TimeSliderLabel_2.Text = 'Orbit';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TelemetryTab = uitab(app.TabGroup2);
            app.TelemetryTab.Title = 'Telemetry';
            app.TelemetryField_2 = uitextarea(app.TelemetryTab);
            app.TelemetryField_2.Editable = 'off';
            app.TelemetryField_2.Tooltip = {'Telemetry data'};
            app.Gauge = uigauge(app.TelemetryTab, 'semicircular');
            app.Gauge.Limits = [-90 90];
            app.Gauge.ScaleDirection = 'counterclockwise';
            app.RocketTab = uitab(app.TabGroup2);
            app.RocketTab.Title = 'Rocket';
            app.Rocket_Parameters = uitextarea(app.RocketTab);
            app.Rocket_Parameters.Editable = 'off';
            app.Rocket_Parameters.Tooltip = {'Telemetry data'};
            app.OrbitTab = uitab(app.TabGroup2);
            app.OrbitTab.Title = 'Orbit';
            app.TelemetryField = uitextarea(app.OrbitTab);
            app.TelemetryField.Editable = 'off';
            app.TelemetryField.Tooltip = {'Telemetry data'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trajectory_Optimizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end